classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
        Menu_3       matlab.ui.container.Menu
        Button_9     matlab.ui.control.Button
        EditField_3  matlab.ui.control.NumericEditField
        Label_5      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_6      matlab.ui.control.Label
        DropDown     matlab.ui.control.DropDown
        Label_4      matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_8     matlab.ui.control.RadioButton
        Button_7     matlab.ui.control.RadioButton
        Button_6     matlab.ui.control.RadioButton
        Button_5     matlab.ui.control.RadioButton
        Button_4     matlab.ui.control.RadioButton
        Button_3     matlab.ui.control.RadioButton
        Button_2     matlab.ui.control.RadioButton
        Button       matlab.ui.control.RadioButton
        UIAxes_2     matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu
        function MenuSelected(app, event)
           [file, path] = uigetfile({'*.jpg;*.png;*.bmp', '图像文件 (*.jpg, *.png, *.bmp)'});
            if isequal(file, 0)
                uialert(app.UIFigure, '未选择图像', '提示');
                return;
            end
            app.OriginalImage = imread(fullfile(path, file)); % 读取图像
            imshow(app.OriginalImage, 'Parent', app.UIAxes); % 显示原始图像
            app.StatusLabel.Text = '状态: 图像加载完成';
            app.ProcessedImage = []; % Clear processed image
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            if isempty(app.ProcessedImage)
                uialert(app.UIFigure, '没有处理后的图像可保存', '错误');
                return;
            end
            [file, path] = uiputfile({'*.jpg;*.png;*.bmp', '图像文件 (*.jpg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            imwrite(app.ProcessedImage, fullfile(path, file)); % 保存图像
            app.StatusLabel.Text = '状态: 图像已保存';
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            delete(app.UIFigure); % 关闭主窗口
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % 获取当前选中的按钮
            selectedButton = app.ButtonGroup.SelectedObject;
            % 根据按钮的 Text 属性执行对应的逻辑
            switch selectedButton.Text
                case '灰度直方图'
                    disp('灰度直方图模块已选择，执行直方图操作...');
                    % 加载图像并计算其灰度直方图
                    [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, '选择图像文件');
                    if isequal(file, 0)
                        disp('未选择图像');
                    else
                        img = imread(fullfile(path, file));
                        grayImg = rgb2gray(img); % 转为灰度图
                        figure, imshow(grayImg), title('灰度图像');
                        figure, imhist(grayImg), title('灰度直方图');
                    end
            
                case '对比度增强'
                    disp('对比度增强模块已选择，执行对比度增强操作...');
                    % 读取图像并进行对比度增强
                    [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, '选择图像文件');
                    if isequal(file, 0)
                        disp('未选择图像');
                    else
                        img = imread(fullfile(path, file));
                        grayImg = rgb2gray(img); % 转为灰度图
                        % 线性对比度增强
                        enhancedLinear = imadjust(grayImg);
                        % 对数变换
                        enhancedLog = uint8(255 * mat2gray(log(1 + double(grayImg))));
                        % 指数变换
                        enhancedExp = uint8(255 * mat2gray(exp(double(grayImg) / 255)));
            
                        % 显示结果
                        figure;
                        subplot(2, 2, 1), imshow(grayImg), title('原灰度图');
                        subplot(2, 2, 2), imshow(enhancedLinear), title('线性增强');
                        subplot(2, 2, 3), imshow(enhancedLog), title('对数变换');
                        subplot(2, 2, 4), imshow(enhancedExp), title('指数变换');
                    end
            
                case '图像变换'
                    disp('图像变换模块已选择，执行图像缩放和旋转操作...');
                    % 读取图像
                    [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, '选择图像文件');
                    if isequal(file, 0)
                        disp('未选择图像');
                    else
                        img = imread(fullfile(path, file));
                        % 动态对话框输入缩放比例和旋转角度
                        prompt = {'请输入缩放比例:', '请输入旋转角度:'};
                        dlgtitle = '图像变换参数';
                        dims = [1 35];
                        definput = {'1', '0'}; % 默认值：比例1，角度0
                        answer = inputdlg(prompt, dlgtitle, dims, definput);
            
                        if isempty(answer)
                            disp('未输入任何参数，操作取消。');
                            return;
                        end
            
                        % 获取用户输入
                        scaleValue = str2double(answer{1});
                        rotateValue = str2double(answer{2});
            
                        % 检查输入的有效性
                        if isnan(scaleValue) || scaleValue <= 0
                            errordlg('请输入有效的缩放比例 (大于 0 的数字)', '输入错误');
                            return;
                        end
                        if isnan(rotateValue)
                            errordlg('请输入有效的旋转角度 (数字)', '输入错误');
                            return;
                        end
            
                        % 图像缩放和旋转
                        scaledImg = imresize(img, scaleValue);
                        rotatedImg = imrotate(scaledImg, rotateValue);
            
                        % 显示结果
                        figure;
                        subplot(1, 3, 1), imshow(img), title('原始图像');
                        subplot(1, 3, 2), imshow(scaledImg), title(['缩放 (比例: ', num2str(scaleValue), ')']);
                        subplot(1, 3, 3), imshow(rotatedImg), title(['旋转 (角度: ', num2str(rotateValue), '°)']);
                    end
            
                case '加噪与滤波'
                    disp('加噪与滤波模块已选择，执行图像加噪与滤波...');
                    % 读取图像
                    [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, '选择图像文件');
                    if isequal(file, 0)
                        disp('未选择图像');
                    else
                        img = imread(fullfile(path, file));
            
                        % 创建交互窗口以输入噪声强度
                        prompt = {'请输入椒盐噪声强度 (0 到 1 之间):'};
                        dlgtitle = '噪声强度';
                        dims = [1 35];
                        definput = {'0.02'}; % 默认噪声强度
                        answer = inputdlg(prompt, dlgtitle, dims, definput);
            
                        % 检查输入是否为空
                        if isempty(answer)
                            disp('未输入噪声强度，操作取消。');
                            return;
                        end
            
                        % 获取噪声强度并验证输入值
                        noiseIntensity = str2double(answer{1});
                        if isnan(noiseIntensity) || noiseIntensity < 0 || noiseIntensity > 1
                            errordlg('请输入 0 到 1 范围内的噪声强度值！', '输入错误');
                            return;
                        end
            
                        % 加噪图像
                        noisyImg = imnoise(img, 'salt & pepper', noiseIntensity);
            
                        % 空域滤波 - 中值滤波
                        medianFiltered = medfilt2(rgb2gray(noisyImg), [3, 3]);
            
                        % 频域滤波 - 低通滤波
                        freqFiltered = lowpass_filter(rgb2gray(noisyImg));
                        % 显示结果
                        figure('Name', '加噪与滤波', 'NumberTitle', 'off');
                        subplot(2, 2, 1), imshow(img), title('原图');
                        subplot(2, 2, 2), imshow(noisyImg), title(['加噪图 (强度: ', num2str(noiseIntensity), ')']);
                        subplot(2, 2, 3), imshow(medianFiltered), title('空域滤波结果');
                        subplot(2, 2, 4), imshow(freqFiltered), title('频域滤波结果');
                    end
            
                otherwise
                    disp('未识别的模块选择...');
            end

  
        end

        % Size changed function: ButtonGroup
        function ButtonGroupSizeChanged(app, event)
            position = app.ButtonGroup.Position;
            disp(['按钮组大小改变: ', mat2str(position)]);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '打开图像';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '保存图像';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '退出程序';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图像显示')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '处理结果显示')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '模块选择';
            app.ButtonGroup.SizeChangedFcn = createCallbackFcn(app, @ButtonGroupSizeChanged, true);
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '灰度直方图';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '对比度增强';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '图像变换';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '加噪与滤波';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '边缘检测';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '目标提取';
            app.Button_7 = uiradiobutton(app.ButtonGroup);
            app.Button_7.Text = '特征提取';
            app.Button_8 = uiradiobutton(app.ButtonGroup);
            app.Button_8.Text = '图像分类';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '噪声强度';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '滤波类型';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'空域', '频域'};
            app.DropDown.Value = '空域';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '旋转角度';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '缩放比例';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '开始执行';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end